classdef HullSectionApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GlobalThicknessReductionPanel   matlab.ui.container.Panel
        StiffenersCheckBox              matlab.ui.control.CheckBox
        PlateDeckCheckBox               matlab.ui.control.CheckBox
        ArcShellCheckBox                matlab.ui.control.CheckBox
        ShellCheckBox                   matlab.ui.control.CheckBox
        ReductionEditField              matlab.ui.control.NumericEditField
        ReductionEditFieldLabel         matlab.ui.control.Label
        EnableUniformReductionCheckBox  matlab.ui.control.CheckBox
        LoadButton                      matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        MirrorCheckBox                  matlab.ui.control.CheckBox
        AutoUnitCheckBox                matlab.ui.control.CheckBox
        res_half                        matlab.ui.control.TextArea
        ResultTextLabel                 matlab.ui.control.Label
        RunAnalysisButton               matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        UserManualTab                   matlab.ui.container.Tab
        TextArea                        matlab.ui.control.TextArea
        ShellTab                        matlab.ui.container.Tab
        SchematicDiagramofShellElementLabel_2  matlab.ui.control.Label
        Image_2                         matlab.ui.control.Image
        DeleteRow_Shell                 matlab.ui.control.Button
        AddRow_Shell                    matlab.ui.control.Button
        ShellTable                      matlab.ui.control.Table
        ArcShellTab                     matlab.ui.container.Tab
        SchematicDiagramofArchShellElementLabel  matlab.ui.control.Label
        Image_3                         matlab.ui.control.Image
        DeleteRow_Arc                   matlab.ui.control.Button
        AddRow_Arc                      matlab.ui.control.Button
        ArcTable                        matlab.ui.control.Table
        PlateDeckTab                    matlab.ui.container.Tab
        SchematicDiagramofPlateDeckElementLabel  matlab.ui.control.Label
        Image_4                         matlab.ui.control.Image
        DeleteRow_Plate                 matlab.ui.control.Button
        AddRow_Plate                    matlab.ui.control.Button
        PlateTable                      matlab.ui.control.Table
        StiffenerTab                    matlab.ui.container.Tab
        SchematicDiagramofStiffenerElementLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        DeleteRow_Stif                  matlab.ui.control.Button
        AddRow_Stif                     matlab.ui.control.Button
        StifTable                       matlab.ui.control.Table
        FullAxes                        matlab.ui.control.UIAxes
        HalffAxes                       matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
            function updateRowNumbers(app, tableType)
            % Update the Row numbers on the left side of the table, such as (1, 2, 3...).
            switch tableType
                case 'Shell'
                    n = size(app.ShellTable.Data, 1);
                    if n > 0
                        app.ShellTable.RowName = cellstr(string(1:n));
                    else
                        app.ShellTable.RowName = {};
                    end
                
                case 'Arc'
                    n = size(app.ArcTable.Data, 1);
                    if n > 0
                        app.ArcTable.RowName = cellstr(string(1:n));
                    else
                        app.ArcTable.RowName = {};
                    end
                
                case 'Plate'
                    n = size(app.PlateTable.Data, 1);
                    if n > 0
                        app.PlateTable.RowName = cellstr(string(1:n));
                    else
                        app.PlateTable.RowName = {};
                    end
                
                case 'Stif'
                    n = size(app.StifTable.Data, 1);
                    if n > 0
                        app.StifTable.RowName = cellstr(string(1:n));
                    else
                        app.StifTable.RowName = {};
                    end
            end
        end    
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % ===== Force Light Theme =====

            app.UIFigure.Color = [1 1 1];
            
            app.TextArea.BackgroundColor = [1 1 1];
            app.TextArea.FontColor = [0 0 0];
            
            app.res_half.BackgroundColor = [1 1 1];
            app.res_half.FontColor = [0 0 0];
            
            app.GlobalThicknessReductionPanel.BackgroundColor = [1 1 1];
            app.GlobalThicknessReductionPanel.ForegroundColor = [0 0 0];
            
            app.RunAnalysisButton.BackgroundColor = [0.94 0.94 0.94];
            app.SaveButton.BackgroundColor = [0.94 0.94 0.94];
            app.LoadButton.BackgroundColor = [0.94 0.94 0.94];
            
            app.RunAnalysisButton.FontColor = [0 0 0];
            app.SaveButton.FontColor = [0 0 0];
            app.LoadButton.FontColor = [0 0 0];

            % ===== Force Light Theme =====
        
            app.UIFigure.Color = [1 1 1];
        
            app.TextArea.BackgroundColor = [1 1 1];
            app.TextArea.FontColor = [0 0 0];
        
            app.res_half.BackgroundColor = [1 1 1];
            app.res_half.FontColor = [0 0 0];
        
            app.FullAxes.Color = [1 1 1];
            app.FullAxes.XColor = [0 0 0];
            app.FullAxes.YColor = [0 0 0];
        
            app.HalffAxes.Color = [1 1 1];
            app.HalffAxes.XColor = [0 0 0];
            app.HalffAxes.YColor = [0 0 0];
            % ------------------------------------------------------------------------------------------------------------------
            % ===================== Set preset value when opening the app =====================
            app.MirrorCheckBox.Value = true;
            app.AutoUnitCheckBox.Value = true;

            % ================== Shells Table ==================
            app.ShellTable.Data = [ ...
                1.0, 0.0, 0.005, 0.0, 0.0, 0.0, 0.005; ...
                1.0, 1.0, 0.005, 1.0, 0.0, 0.0, 0.005; ...
                0.0, 1.0, 0.005, 2.0, 1.0, 0.0, 0.005];

            % ================== Arc Shells Table (empty) ==================
            app.ArcTable.Data = zeros(0, 9);

            % ================== Plates / Decks Table ==================
            app.PlateTable.Data = [2.0, 0.0, 0.01, 0.0, 2.0, 0.0, 0.01];

            % ================== Stiffeners Table ==================
            app.StifTable.Data = { ...
            't', false, '', 0.08, 0.01, 0.04, 0.01, 0.70, 1.92, 0, 0.0, 0.01, 0.01; ...
            't', false, '', 0.08, 0.01, 0.04, 0.01, 1.40, 1.92, 0, 0.0, 0.01, 0.01; ...
            'a', false, 'left', 0.08, 0.01, 0.08, 0.01, 1.00, 1.92, 0, 0.0, 0.01, 0.01};

            % ===================== Set table properties =====================
            app.ShellTable.ColumnName = {'y Length [m]','z Length [m]','Thickness(t) [m]','y Axis Pos [m]','z Axis Pos [m]','t Reduction [mm]','t Effective [m]'};
            app.ArcTable.ColumnName = {'y Axis Centre [m]','z Axis Centre [m]','Radius [m]','Thickness(t) [m]','Start Angle [degree]','End Angle [degree]','Segments','t Reduction [mm]','t Effective [m]'};
            app.PlateTable.ColumnName = {'y Length [m]','z Length [m]','Thickness(t) [m]','y Axis Pos [m]','z Axis Pos [m]','t Reduction [mm]','t Effective [m]'};
            app.StifTable.ColumnName = {'Type(t/a)','No Reverse','Arm (left/right)','Web Height [m]','Web Thickness(wt) [m]','Flange Width [m]','Flange Thickness(ft) [m]','y Axis Pos [m]','z Axis Pos [m]', 'Rotation Angle [degree]','t Reduction [mm]','wt Effective [m]','ft Effective [m]'};

            app.ShellTable.ColumnEditable = [true true true true true true false];
            app.ArcTable.ColumnEditable = [true true true true true true true true false];
            app.PlateTable.ColumnEditable = [true true true true true true false];
            app.StifTable.ColumnEditable = [true true true true true true true true true true true false false];

            % Make the type column of StifTable display as a string
            app.StifTable.ColumnFormat = {'char', 'logical', 'char', 'numeric', 'numeric', ...
                                        'numeric', 'numeric', 'numeric', 'numeric'};

            % Initialize Row number
            updateRowNumbers(app, 'Shell');
            updateRowNumbers(app, 'Arc');
            updateRowNumbers(app, 'Plate');
            updateRowNumbers(app, 'Stif');

            % If a preset configuration file exists, it will be loaded automatically
            if isfile('HullSection_Default.mat')
                try
                    loaded = load('HullSection_Default.mat');
                    % Restore data (same as LoadButtonPushed code)
                catch
                end
            end

            % ~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~

        end

        % Button pushed function: RunAnalysisButton
        function RunAnalysisButtonPushed(app, event)
            % ------------------------------------------------------------------------------------------------------------------
            % ================== 1. Read UI data ==================
            mirror_to_port = app.MirrorCheckBox.Value;
            auto_mm_to_m   = app.AutoUnitCheckBox.Value;

            % Shells
            data = app.ShellTable.Data;
            Shell_y_len = data(:,1); 
            Shell_z_len = data(:,2);
            Shell_t   = data(:,3); 
            Shell_pos_y = data(:,4); 
            Shell_pos_z = data(:,5);
            Shell_t_reduce = data(:,6);

            % Arc Shells
            data = app.ArcTable.Data;
            Arc_Yc = data(:,1); 
            Arc_Zc = data(:,2); 
            Arc_R = data(:,3);
            Arc_t_m = data(:,4); 
            Arc_theta1 = deg2rad(data(:,5));
            Arc_theta2 = deg2rad(data(:,6));
            Arc_nSeg = data(:,7);
            Arc_t_reduce = data(:,8);


            % Plates
            data = app.PlateTable.Data;
            Plate_y_len = data(:,1); 
            Plate_z_len = data(:,2);
            Plate_t = data(:,3); 
            Plate_pos_y = data(:,4); 
            Plate_pos_z = data(:,5);
            Plate_t_reduce = data(:,6);


            % ================== Stiffeners ==================
            data = app.StifTable.Data;   % cell array
    
            Stif_qun = size(data, 1);
            if Stif_qun > 0
                Stif_type       = string(data(:,1));
                Stif_no_reverse = logical(cell2mat(data(:,2)));
                Stif_arm        = string(data(:,3));
                Stif_web_h      = cell2mat(data(:,4));
                Stif_web_t_m      = cell2mat(data(:,5));
                Stif_fl_w       = cell2mat(data(:,6));
                Stif_fl_t_m       = cell2mat(data(:,7));
                Stif_pos_y      = cell2mat(data(:,8));
                Stif_pos_z      = cell2mat(data(:,9));
                Stif_rotation   = cell2mat(data(:,10));
                Stif_t_reduce   = cell2mat(data(:,11));

            else
                Stif_type = string([]); 
                Stif_no_reverse = logical([]); 
                Stif_arm = string([]); 
                Stif_web_h = []; Stif_web_t_m = []; Stif_fl_w = []; 
                Stif_fl_t_m = []; Stif_pos_y = []; Stif_pos_z = [];
            end

            % ================== 2. Unit correction ==================
            if auto_mm_to_m
                Shell_t(Shell_t > 0.2) = Shell_t(Shell_t > 0.2) / 1000;
                Arc_t_m(Arc_t_m > 0.2)         = Arc_t_m(Arc_t_m > 0.2) / 1000;
                Plate_t(Plate_t > 0.2) = Plate_t(Plate_t > 0.2) / 1000;
                Stif_web_t_m(Stif_web_t_m > 0.2) = Stif_web_t_m(Stif_web_t_m > 0.2) / 1000;
                Stif_fl_t_m(Stif_fl_t_m > 0.2)   = Stif_fl_t_m(Stif_fl_t_m > 0.2) / 1000;
            end

            % ================== 2.5 Thickness Reduction ==================

            % ---------- Shell ----------
            Shell_t_m = Shell_t - Shell_t_reduce/1000;   % mm → m
            
            % Mistake prevention: Avoid negative thickness
            Shell_t_m(Shell_t_m <= 0) = 0;
            
            % ---------- Plate ----------
            Plate_t_m = Plate_t - Plate_t_reduce/1000;
            Plate_t_m(Plate_t_m <= 0) = 0;
            
            % ---------- Arc Shell ----------
            Arc_t = Arc_t_m - Arc_t_reduce/1000;
            Arc_t(Arc_t <= 0) = 0;
            
            % ---------- Stiffener ----------
            Stif_web_t = Stif_web_t_m - Stif_t_reduce/1000;
            Stif_fl_t  = Stif_fl_t_m  - Stif_t_reduce/1000;
            
            Stif_web_t(Stif_web_t <= 0) = 0;
            Stif_fl_t(Stif_fl_t <= 0) = 0;

            % ================== 2.6 Update Table (Display Only) ==================

            data = app.ShellTable.Data;
            data(:,7) = Shell_t_m;
            app.ShellTable.Data = data;

            data = app.PlateTable.Data;
            data(:,7) = Plate_t_m;
            app.PlateTable.Data = data;
            
            data = app.ArcTable.Data;
            data(:,9) = Arc_t;
            app.ArcTable.Data = data;


            if Stif_qun > 0
                app.StifTable.Data(:,12) = num2cell(Stif_web_t);
                app.StifTable.Data(:,13) = num2cell(Stif_fl_t);
            end

            % ================== 2.7 thickness % reduction ==================
            if app.EnableUniformReductionCheckBox.Value

                p = app.ReductionEditField.Value/100;
            

                if app.ShellCheckBox.Value
                    Shell_t_m = Shell_t_m * (1-p);
                end
            
                if app.ArcShellCheckBox.Value
                    Arc_t = Arc_t * (1-p);
                end
            
                if app.PlateDeckCheckBox.Value
                    Plate_t_m = Plate_t_m * (1-p);
                end
            
                if app.StiffenersCheckBox.Value
                    Stif_web_t = Stif_web_t * (1-p);
                    Stif_fl_t  = Stif_fl_t * (1-p);
                end
            end

            % ================== 3. Create Polygons ==================
            polys = {};
            polys = [polys; make_plate_polys(Shell_pos_y, Shell_pos_z, Shell_y_len, Shell_z_len, Shell_t_m, "Shell")];
            polys = [polys; make_plate_polys(Plate_pos_y, Plate_pos_z, Plate_y_len, Plate_z_len, Plate_t_m, "Plate")];

            % Arc Shells
            if ~isempty(Arc_Yc) && any(Arc_R > 0)
                for i = 1:length(Arc_Yc)
                    if Arc_R(i) > 0
                        poly_arc = make_arc_band_poly(Arc_Yc(i), Arc_Zc(i), Arc_R(i), ...
                            Arc_theta1(i), Arc_theta2(i), Arc_t(i), Arc_nSeg(i), 'ArcShell');
                        polys{end+1,1} = poly_arc;
                    end
                end
            end

            % Stiffeners
            if Stif_qun > 0
                polys = [polys; make_stiffener_polys(Stif_pos_y, Stif_pos_z, ...
                    Stif_web_h, Stif_web_t, Stif_fl_w, Stif_fl_t, ...
                    Stif_type, Stif_no_reverse, Stif_arm, Stif_rotation)];
            end

            % ================== 4. Calculate Half & Full Section ==================
            res_half = composite_from_polys_YZ(polys);

            if mirror_to_port
                polys_all = [polys; mirror_polys_Y(polys)];
                res_full = composite_from_polys_YZ(polys_all);
            else
                polys_all = polys;
                res_full = res_half;
            end

            % ================== 5. Present Result ==================
            resultStr = sprintf(['=== Input Section ===\n' ...
                'Cross-sectional area   = %.6f m²\n' ...
                'Neutral axis (N.A.)       = %.4f m\n\n' ...
                '=== Full Section ===\n' ...
                'Cross-sectional area    = %.6f m²\n' ...
                'Neutral axis (N.A.)        = %.4f m\n' ...
                'Moment of inertia (Iyy)  = %.6f m⁴\n' ...
                'Deck section modulus   = %.6f m³\n' ...
                'Keel section modulus    = %.6f m³'], ...
                res_half.A, res_half.ybar, ...
                res_full.A, res_full.ybar, res_full.Iyy, res_full.Zdeck, res_full.Zkeel);
            
            app.res_half.Value = resultStr;     % res_half

            % ================== 6. Polygon ==================
            % Clear
            cla(app.FullAxes, 'reset');
            app.FullAxes.Color = [1 1 1];
            app.FullAxes.XColor = [0 0 0];
            app.FullAxes.YColor = [0 0 0];

            cla(app.HalffAxes, 'reset');
            app.HalffAxes.Color = [1 1 1];
            app.HalffAxes.XColor = [0 0 0];
            app.HalffAxes.YColor = [0 0 0];

            % ================== Half Section ==================
            ax1 = app.FullAxes;
            ax1.Color = [1 1 1];
            ax1.XColor = [0 0 0];
            ax1.YColor = [0 0 0];

            hold(ax1, 'on');
            axis(ax1, 'equal');
            grid(ax1, 'on');
            title(ax1, 'Input Section', 'FontSize', 18);
            xlabel(ax1, 'Transverse, y [m]', 'FontSize', 18);
            ylabel(ax1, 'Depth, z [m]', 'FontSize', 18);

            draw_polys(ax1, polys, [0.2 0.6 0.9], 0.5);

            yline(ax1, 0, 'b-', 'Baseline Z=0', 'LabelVerticalAlignment','bottom');
            xline(ax1, 0, 'b-', 'Centreline Y=0', 'LabelVerticalAlignment','top');

            if isfield(res_half, 'ybar')
                plot(ax1, xlim(ax1), [res_half.ybar res_half.ybar], 'r--', 'LineWidth', 1);
                text(ax1, min(xlim(ax1))+0.03*diff(xlim(ax1)), ...
                    res_half.ybar + 0.02*diff(ylim(ax1)), ...
                    sprintf('N.A. Z = %.3f m', res_half.ybar), ...
                    'Color','r','FontWeight','bold');
            end

            % ================== Full Section ==================
            ax2 = app.HalffAxes;
            ax2.Color = [1 1 1];
            ax2.XColor = [0 0 0];
            ax2.YColor = [0 0 0];

            hold(ax2, 'on');
            axis(ax2, 'equal');
            grid(ax2, 'on');
            title(ax2, 'Full Section (Port & Starboard)', 'FontSize', 18);
            xlabel(ax2, 'Transverse, y [m]', 'FontSize', 18);
            ylabel(ax2, 'Depth, z [m]', 'FontSize', 18);

            if mirror_to_port && exist('polys_all','var')
                draw_polys(ax2, polys_all, [0.2 0.8 0.6], 0.5);
                yline(ax2, 0, 'b-', 'Baseline Z=0', 'LabelVerticalAlignment','bottom');
                xline(ax2, 0, 'b-', 'Centreline Y=0', 'LabelVerticalAlignment','top');

                if isfield(res_full, 'ybar')
                    plot(ax2, xlim(ax2), [res_full.ybar res_full.ybar], 'm--', 'LineWidth', 1);
                    text(ax2, min(xlim(ax2))+0.03*diff(xlim(ax2)), ...
                        res_full.ybar + 0.02*diff(ylim(ax2)), ...
                        sprintf('N.A. Z = %.3f m', res_full.ybar), ...
                        'Color','m','FontWeight','bold');
                end
            else
                draw_polys(ax2, polys, [0.2 0.6 0.9], 0.5);
                text(ax2, 0.1, 0.1, 'Mirroring disabled', 'Color', 'r', 'FontSize', 12);
            end

            % Automatically adjust display range
            axis(ax1, 'tight');
            axis(ax2, 'tight');
            % ~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~

            % ------------------------------------------------------------------------------------------------------------------
            % ===================== Utility Functions from Original Script =====================
            function polys = make_plate_polys(pos_y, pos_z, y_len, z_len, t, tag)
                n = numel(pos_y);
                polys = cell(0,1);
                for i = 1:n
                    dy = y_len(i);
                    dz = z_len(i);
                    ti = t(i);
                    P0 = [pos_y(i), pos_z(i)];
                    L = hypot(dy,dz);
                    if L == 0 || ti == 0, continue; end
                    u = [dy, dz] / L;
                    nvec = [-u(2), u(1)];
                    P1 = P0 + [dy, dz];
                    C1 = P0 + (ti/2) * nvec;
                    C2 = P1 + (ti/2) * nvec;
                    C3 = P1 - (ti/2) * nvec;
                    C4 = P0 - (ti/2) * nvec;
                    poly.Y = [C1(1) C2(1) C3(1) C4(1)];
                    poly.Z = [C1(2) C2(2) C3(2) C4(2)];
                    poly.tag = tag;
                    polys{end+1,1} = poly;
                end
            end

            function poly = make_arc_band_poly(Yc, Zc, R, theta1, theta2, t, nSeg, tag)
                Rout = R + t/2;
                Rin = R - t/2;
                if Rin <= 0
                    error('Arc thickness too large relative to radius.');
                end

                % angle rotate as counter-clockwise
                dth = theta2 - theta1;
                if dth <= 0
                    dth = dth + 2*pi;
                end
                
                th_out = linspace(theta1, theta1 + dth, nSeg);
                th_in = linspace(theta1, theta1 + dth, nSeg);

                % Outer arc
                Y_out = Yc + Rout * cos(th_out);
                Z_out = Zc + Rout * sin(th_out);

                % Inner arc (reverse order for CCW)
                Y_in = Yc + Rin * cos(th_in);
                Z_in = Zc + Rin * sin(th_in);

                Y = [Y_out, fliplr(Y_in)];
                Z = [Z_out, fliplr(Z_in)];

                poly.Y = Y;
                poly.Z = Z;
                poly.tag = tag;
            end

            %
            function polys = make_stiffener_polys(pos_y, pos_z, web_h, web_t, fl_w, fl_t, ...
                                       stif_type, stif_no_reverse, stif_arm, rotation_deg)
    
                if isempty(pos_y)
                    polys = {};
                    return;
                end
                
                n = numel(pos_y);
                polys = cell(0,1);
                
                for i = 1:n
                    P0 = [pos_y(i), pos_z(i)];                    % intersection position
                    theta = deg2rad(rotation_deg(i));             % convert to radians
                    
                    wt = web_t(i);  wh = web_h(i);
                    ft = fl_t(i);   fw = fl_w(i);

                    if wt<=0 || wh<=0
                        draw_web = false;
                    else
                        draw_web = true;
                    end
                    
                    if ft<=0 || fw<=0
                        draw_flange = false;
                    else
                        draw_flange = true;
                    end
                    
                    %if wt<=0 || wh<=0 || ft<=0 || fw<=0, continue; end
            
                    % ================== 1. Web ==================
                    dx = wh * sin(theta);
                    dz = wh * cos(theta);
                    
                    webP0 = P0;
                    webP1 = P0 + [dx, dz];
                    
                    % The normal vector of the web (perpendicular to the web direction)
                    len = hypot(dx, dz);
                    nx = -dz / len;
                    nz =  dx / len;
                    
                    C1 = webP0 + (wt/2) * [nx, nz];
                    C2 = webP1 + (wt/2) * [nx, nz];
                    C3 = webP1 - (wt/2) * [nx, nz];
                    C4 = webP0 - (wt/2) * [nx, nz];
                    
                    poly_web.Y = [C1(1) C2(1) C3(1) C4(1)];
                    poly_web.Z = [C1(2) C2(2) C3(2) C4(2)];
                    poly_web.tag = 'Stf-Web';
                    %polys{end+1,1} = poly_web;
                    if draw_web
                        polys{end+1,1} = poly_web;
                    end
                                
                    % ================== 2. Flange ==================

                    % The direction vector of the web
                    tw = [dx, dz] / len;

                    % Flange direction is perpendicular to the web
                    tf = [-tw(2), tw(1)];     % flange length direction
                    
                    % Flange thickness direction is same as web.
                    nf = tw;                 % flange thickness direction
                    
                    switch lower(stif_type(i))
                        case "t"
                            % --------- T section (symmetry) ----------
                            half_fl = fw / 2;
                    
                            P1 = P0 - half_fl * tf;   % flange side 1
                            P2 = P0 + half_fl * tf;   % flange side 2
                    
                            poly_fl.tag = 'Stf-Fl(T)';
                    
                        case "a"
                            % --------- L section ----------
                            arm = "left";
                            if i <= numel(stif_arm) && strlength(stif_arm(i)) > 0
                                arm = lower(stif_arm(i));
                            end
                    
                            if strcmp(arm, "left")
                                P1 = P0;
                                P2 = P0 - fw * tf;
                            else
                                P1 = P0;
                                P2 = P0 + fw * tf;
                            end
                    
                            poly_fl.tag = 'Stf-Fl(L)';
                    
                        otherwise
                            error('stif_type must be "t" or "a"');
                    end
                    
                    % ---- Construct a rectangle using the flange centerline and thickness ----
                    C1 = P1 + (ft/2) * nf;
                    C2 = P2 + (ft/2) * nf;
                    C3 = P2 - (ft/2) * nf;
                    C4 = P1 - (ft/2) * nf;
                    
                    poly_fl.Y = [C1(1), C2(1), C3(1), C4(1)];
                    poly_fl.Z = [C1(2), C2(2), C3(2), C4(2)];
                    
                    if draw_flange
                        polys{end+1,1} = poly_fl;
                    end
                end
            end

            function res = composite_from_polys_YZ(polys)
                A_sum = 0; Ay_sum = 0;
                zmin = +inf; zmax = -inf;
                elems = struct('A',{},'zc',{},'Iyy_c',{});
                for k = 1:numel(polys)
                    [poly_ccw, A, ~, zc, Iyy_origin] = poly_ccw_props_YZ(polys{k}.Y, polys{k}.Z);
                    polys{k} = poly_ccw;
                    Iyy_c = Iyy_origin - A*(zc^2);
                    elems(k).A = A; elems(k).zc = zc; elems(k).Iyy_c = Iyy_c;
                    A_sum = A_sum + A;
                    Ay_sum = Ay_sum + A*zc;
                    zmin = min([zmin, poly_ccw.Z]);
                    zmax = max([zmax, poly_ccw.Z]);
                end
                ybar = Ay_sum / A_sum;
                Iyy = 0;
                for k = 1:numel(elems)
                    dz = elems(k).zc - ybar;
                    Iyy = Iyy + (elems(k).Iyy_c + elems(k).A * dz^2);
                end
                res.A = A_sum; 
                res.ybar = ybar; 
                res.Iyy = Iyy;
                res.Zdeck = Iyy / (zmax - ybar);
                res.Zkeel = Iyy / (ybar - zmin);
            end

            function [poly_out, Apos, yc, zc, Iyy_origin] = poly_ccw_props_YZ(Y, Z)
                [A, yc, zc, Iyy0, ~, ~] = poly_props_YZ(Y, Z);
                if A < 0
                    Y = fliplr(Y); Z = fliplr(Z);
                    [A, yc, zc, Iyy0, ~, ~] = poly_props_YZ(Y, Z);
                end
                Apos = A;
                Iyy_origin = Iyy0;
                poly_out.Y = Y; 
                poly_out.Z = Z; 
                poly_out.tag = "";
            end

            function [A, yc, zc, Iyy, Izz, Iyz] = poly_props_YZ(Y, Z)
                Yv = Y(:); Zv = Z(:);
                Y2 = [Yv; Yv(1)]; Z2 = [Zv; Zv(1)];
                cross = Y2(1:end-1).*Z2(2:end) - Y2(2:end).*Z2(1:end-1);
                A = 0.5 * sum(cross);
                yc = sum((Y2(1:end-1)+Y2(2:end)).*cross) / (6*A);
                zc = sum((Z2(1:end-1)+Z2(2:end)).*cross) / (6*A);
                Izz0 = (1/12)*sum((Z2(1:end-1).^2 + Z2(1:end-1).*Z2(2:end) + Z2(2:end).^2).*cross);
                Iyy0 = (1/12)*sum((Y2(1:end-1).^2 + Y2(1:end-1).*Y2(2:end) + Y2(2:end).^2).*cross);
                Iyz  = (1/24)*sum((Y2(1:end-1).*(2*Z2(1:end-1)+Z2(2:end)) + Y2(2:end).*(2*Z2(2:end)+Z2(1:end-1))).*cross);
                Iyy = Izz0; 
                Izz = Iyy0;
            end

            function polys_m = mirror_polys_Y(polys, tol)
                if nargin < 2, tol = 1e-9; end
                polys_m = cell(0,1);
                for k = 1:numel(polys)
                    [poly_ccw, ~, yc, ~, ~] = poly_ccw_props_YZ(polys{k}.Y, polys{k}.Z);
                    if abs(yc) <= tol, continue; end
                    poly_m = poly_ccw;
                    poly_m.Y = -poly_m.Y;
                    polys_m{end+1,1} = poly_m;
                end
            end

            function draw_polys(ax, polys, col, alpha)
                for k = 1:numel(polys)
                    patch(ax, polys{k}.Y, polys{k}.Z, col, ...
                        'FaceAlpha', alpha, ...
                        'EdgeColor', 'k', ...
                        'LineWidth', 1.0);
                end
            end
            % ~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~

            
        end

        % Button pushed function: AddRow_Shell
        function AddRow_ShellButtonPushed(app, event)
            % -----------------------------------------------------------------------------------------------------------------
            app.ShellTable.Data(end+1, :) = [0, 0, 0.005, 0, 0, 0, 0];
            updateRowNumbers(app, 'Shell');
            % ~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~
        end

        % Button pushed function: DeleteRow_Shell
        function DeleteRow_ShellButtonPushed(app, event)
            % -----------------------------------------------------------------------------------------------------------------
            if size(app.ShellTable.Data,1) > 0
                app.ShellTable.Data(end,:) = [];
            end
            updateRowNumbers(app, 'Shell');
            % ~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~
        end

        % Button pushed function: AddRow_Arc
        function AddRow_ArcButtonPushed(app, event)
            % -----------------------------------------------------------------------------------------------------------------
            app.ArcTable.Data(end+1, :) = [0, 0, 0, 0.005, 0, 0, 40, 0, 0];
            updateRowNumbers(app, 'Arc');
            % ~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~
        end

        % Button pushed function: DeleteRow_Arc
        function DeleteRow_ArcButtonPushed(app, event)
            % -----------------------------------------------------------------------------------------------------------------
            if size(app.ArcTable.Data,1) > 0
                app.ArcTable.Data(end,:) = [];
            end
            updateRowNumbers(app, 'Arc');
            % ~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~
        end

        % Button pushed function: AddRow_Plate
        function AddRow_PlateButtonPushed(app, event)
            % -----------------------------------------------------------------------------------------------------------------
            app.PlateTable.Data(end+1, :) = [0, 0, 0.01, 0, 0, 0, 0];
            updateRowNumbers(app, 'Plate');
            % ~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~
        end

        % Button pushed function: DeleteRow_Plate
        function DeleteRow_PlateButtonPushed(app, event)
            % -----------------------------------------------------------------------------------------------------------------
            if size(app.PlateTable.Data,1) > 0
                app.PlateTable.Data(end,:) = [];
            end
            updateRowNumbers(app, 'Plate');
            % ~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~
        end

        % Button pushed function: AddRow_Stif
        function AddRow_StifButtonPushed(app, event)
            % -----------------------------------------------------------------------------------------------------------------
            app.StifTable.Data(end+1, :) = {'t', false, '', 0.08, 0.01, 0.04, 0.01, 0, 1.92, 0, 0, 0, 0};
            updateRowNumbers(app, 'Stif');
            % ~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~
        end

        % Button pushed function: DeleteRow_Stif
        function DeleteRow_StifButtonPushed(app, event)
            % -----------------------------------------------------------------------------------------------------------------
            if size(app.StifTable.Data,1) > 0
                app.StifTable.Data(end,:) = [];
            end
            updateRowNumbers(app, 'Stif');
            % ~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % -----------------------------------------------------------------------------------------------------------------
            % 收集所有表格資料
            data.Shell     = app.ShellTable.Data;
            data.Arc       = app.ArcTable.Data;
            data.Plate     = app.PlateTable.Data;
            data.Stif      = app.StifTable.Data;
            data.Mirror    = app.MirrorCheckBox.Value;
            data.AutoUnit  = app.AutoUnitCheckBox.Value;

            % 讓使用者選擇儲存位置與檔名
            [file, path] = uiputfile('*.mat', '儲存設定檔', 'HullSection_Setting.mat');
            if isequal(file, 0)
                return;  % 使用者取消
            end
        
            save(fullfile(path, file), 'data');
            uialert(app.UIFigure, 'Inputs have been successfully saved!!', 'Save Complete', 'Icon', 'success');
            % ~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % -----------------------------------------------------------------------------------------------------------------
            % Allow users to select the .mat file to load.
            [file, path] = uigetfile('*.mat', '載入設定檔');
            if isequal(file, 0)
                return;
            end
        
            try
                loaded = load(fullfile(path, file));
                data = loaded.data;

                % Restore form data
                app.ShellTable.Data = data.Shell;
                app.ArcTable.Data   = data.Arc;
                app.PlateTable.Data = data.Plate;
                app.StifTable.Data  = data.Stif;

                % Restore the checkbox
                app.MirrorCheckBox.Value = data.Mirror;
                app.AutoUnitCheckBox.Value = data.AutoUnit;

                % Update Row Number
                updateRowNumbers(app, 'Shell');
                updateRowNumbers(app, 'Arc');
                updateRowNumbers(app, 'Plate');
                updateRowNumbers(app, 'Stif');

                uialert(app.UIFigure, 'Inputs have been successfully loaded!!', 'Loading Complete', 'Icon', 'success');

            catch
                uialert(app.UIFigure, 'Loading failed！The file format may be incorrect.', 'Error', 'Icon', 'error');
            end
            % ~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HalffAxes = uiaxes(app.UIFigure);
            title(app.HalffAxes, 'Full Section (Port & Starboard)')
            xlabel(app.HalffAxes, 'Transverse, y [m]')
            ylabel(app.HalffAxes, 'Depth, z [m]')
            zlabel(app.HalffAxes, 'Z')
            app.FullAxes = uiaxes(app.UIFigure);
            title(app.FullAxes, 'Input Section')
            xlabel(app.FullAxes, 'Transverse, y [m]')
            ylabel(app.FullAxes, 'Depth, z [m]')
            zlabel(app.FullAxes, 'Z')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.UserManualTab = uitab(app.TabGroup);
            app.UserManualTab.Title = 'User Manual';
            app.TextArea = uitextarea(app.UserManualTab);
            app.TextArea.Value = {' 1. Application Overview'; 'This application evaluates hull cross‑section properties for longitudinal strength analysis. '; 'Typical workflow:'; ' a) Select a structural member tab (Shell / Plate / Stiffener)'; ' b) Define geometry parameters for each element'; ' c) Review the section geometry in the plot window'; ' d) Run analysis to obtain section properties'; ' f) Save input parameters if required, allowing reload or sharing for further studies'; ''; '///////////////////////////////////////////////////////////////////////////////////////////'; ''; ''; ''; '2. Structural Member Tabs'; 'Each tab represents one category of longitudinal structural members.'};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
            app.ShellTab = uitab(app.TabGroup);
            app.ShellTab.Title = 'Shell';
            app.ShellTable = uitable(app.ShellTab);
            app.ShellTable.ColumnName = {'y Length [m]'; 'z Length [m]'; 'Thickness [m]'; 'y Axis Pos [m]'; 'z Axis Pos [m]'; 'Reduction [mm]'; 'Effective [m]'};
            app.ShellTable.RowName = {};
            app.ShellTable.ColumnEditable = true;
            app.AddRow_Shell = uibutton(app.ShellTab, 'push');
            app.AddRow_Shell.ButtonPushedFcn = createCallbackFcn(app, @AddRow_ShellButtonPushed, true);
            app.AddRow_Shell.Text = 'Add Element';
            app.DeleteRow_Shell = uibutton(app.ShellTab, 'push');
            app.DeleteRow_Shell.ButtonPushedFcn = createCallbackFcn(app, @DeleteRow_ShellButtonPushed, true);
            app.DeleteRow_Shell.Text = 'Delete Element';
            app.Image_2 = uiimage(app.ShellTab);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'Shell_Definition.png');
            app.SchematicDiagramofShellElementLabel_2 = uilabel(app.ShellTab);
            app.SchematicDiagramofShellElementLabel_2.Text = 'Schematic Diagram of Shell Element';
            app.ArcShellTab = uitab(app.TabGroup);
            app.ArcShellTab.Title = 'Arc Shell';
            app.ArcTable = uitable(app.ArcShellTab);
            app.ArcTable.ColumnName = {'Yc'; 'Zc'; 'R'; 't'; 'theta1'; 'theta2'; 'nSeg'; 'Reduction [mm]'; 'Effective [m]'};
            app.ArcTable.RowName = {};
            app.ArcTable.ColumnEditable = true;
            app.AddRow_Arc = uibutton(app.ArcShellTab, 'push');
            app.AddRow_Arc.ButtonPushedFcn = createCallbackFcn(app, @AddRow_ArcButtonPushed, true);
            app.AddRow_Arc.Text = 'Add Element';
            app.DeleteRow_Arc = uibutton(app.ArcShellTab, 'push');
            app.DeleteRow_Arc.ButtonPushedFcn = createCallbackFcn(app, @DeleteRow_ArcButtonPushed, true);
            app.DeleteRow_Arc.Text = 'Delete Element';
            app.Image_3 = uiimage(app.ArcShellTab);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'ArcShell_Definition.png');
            app.SchematicDiagramofArchShellElementLabel = uilabel(app.ArcShellTab);
            app.SchematicDiagramofArchShellElementLabel.Text = 'Schematic Diagram of Arch Shell Element';
            app.PlateDeckTab = uitab(app.TabGroup);
            app.PlateDeckTab.Title = 'Plate / Deck';
            app.PlateTable = uitable(app.PlateDeckTab);
            app.PlateTable.ColumnName = {'y Length [m]'; 'z Length [m]'; 'Thickness [m]'; 'y Axis Pos [m]'; 'z Axis Pos [m]'; 'Reduction [mm]'; 'Effective [m]'};
            app.PlateTable.RowName = {};
            app.AddRow_Plate = uibutton(app.PlateDeckTab, 'push');
            app.AddRow_Plate.ButtonPushedFcn = createCallbackFcn(app, @AddRow_PlateButtonPushed, true);
            app.AddRow_Plate.Text = 'Add Element';
            app.DeleteRow_Plate = uibutton(app.PlateDeckTab, 'push');
            app.DeleteRow_Plate.ButtonPushedFcn = createCallbackFcn(app, @DeleteRow_PlateButtonPushed, true);
            app.DeleteRow_Plate.Text = 'Delete Element';
            app.Image_4 = uiimage(app.PlateDeckTab);
            app.Image_4.ImageSource = fullfile(pathToMLAPP, 'Plate_Definition.png');
            app.SchematicDiagramofPlateDeckElementLabel = uilabel(app.PlateDeckTab);
            app.SchematicDiagramofPlateDeckElementLabel.Text = 'Schematic Diagram of Plate/Deck Element';
            app.StiffenerTab = uitab(app.TabGroup);
            app.StiffenerTab.Title = 'Stiffener';
            app.StifTable = uitable(app.StiffenerTab);
            app.StifTable.ColumnName = {'type'; 'no_reverse'; 'arm'; 'web_h'; 'web_t'; 'fl_w'; 'fl_t'; 'pos_y'; 'pos_z'; 'Reduction [mm]'; 'wt Effective [m]'; 'ft Effective [m]'};
            app.StifTable.RowName = {};
            app.StifTable.ColumnEditable = true;
            app.AddRow_Stif = uibutton(app.StiffenerTab, 'push');
            app.AddRow_Stif.ButtonPushedFcn = createCallbackFcn(app, @AddRow_StifButtonPushed, true);
            app.AddRow_Stif.Text = 'Add Element';
            app.DeleteRow_Stif = uibutton(app.StiffenerTab, 'push');
            app.DeleteRow_Stif.ButtonPushedFcn = createCallbackFcn(app, @DeleteRow_StifButtonPushed, true);
            app.DeleteRow_Stif.Text = 'Delete Element';
            app.Image = uiimage(app.StiffenerTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Stiffeners_Definition.png');
            app.SchematicDiagramofStiffenerElementLabel = uilabel(app.StiffenerTab);
            app.SchematicDiagramofStiffenerElementLabel.Text = 'Schematic Diagram of Stiffener Element';
            app.RunAnalysisButton = uibutton(app.UIFigure, 'push');
            app.RunAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysisButtonPushed, true);
            app.RunAnalysisButton.Text = 'Run Analysis';
            app.ResultTextLabel = uilabel(app.UIFigure);
            app.ResultTextLabel.Text = 'Results';
            app.res_half = uitextarea(app.UIFigure);
            app.AutoUnitCheckBox = uicheckbox(app.UIFigure);
            app.AutoUnitCheckBox.Text = 'Auto m→mm';
            app.MirrorCheckBox = uicheckbox(app.UIFigure);
            app.MirrorCheckBox.Text = 'Mirror Input on Z-axis';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = '💾 SAVE Input';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = '📂LOAD Input';
            app.GlobalThicknessReductionPanel = uipanel(app.UIFigure);
            app.GlobalThicknessReductionPanel.Title = 'Global Thickness Reduction [%]';
            app.EnableUniformReductionCheckBox = uicheckbox(app.GlobalThicknessReductionPanel);
            app.EnableUniformReductionCheckBox.Text = 'Enable Uniform Reduction';
            app.ReductionEditFieldLabel = uilabel(app.GlobalThicknessReductionPanel);
            app.ReductionEditFieldLabel.Text = 'Reduction [%]';
            app.ReductionEditField = uieditfield(app.GlobalThicknessReductionPanel, 'numeric');
            app.ShellCheckBox = uicheckbox(app.GlobalThicknessReductionPanel);
            app.ShellCheckBox.Text = 'Shell';
            app.ArcShellCheckBox = uicheckbox(app.GlobalThicknessReductionPanel);
            app.ArcShellCheckBox.Text = 'Arc Shell';
            app.PlateDeckCheckBox = uicheckbox(app.GlobalThicknessReductionPanel);
            app.PlateDeckCheckBox.Text = 'Plate / Deck';
            app.StiffenersCheckBox = uicheckbox(app.GlobalThicknessReductionPanel);
            app.StiffenersCheckBox.Text = 'Stiffeners';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HullSectionApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end